classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        TabGroup               matlab.ui.container.TabGroup
        MainTab_2              matlab.ui.container.Tab
        GridLayout             matlab.ui.container.GridLayout
        TextArea_3             matlab.ui.control.TextArea
        LoadDataFileButton     matlab.ui.control.Button
        ExportButton           matlab.ui.control.Button
        LoadSpecFileButton     matlab.ui.control.Button
        Hyperlink              matlab.ui.control.Hyperlink
        GDPNowcastingAppLabel  matlab.ui.control.Label
        GraphTab               matlab.ui.container.Tab
        UITable3               matlab.ui.control.Table
        RunGraphButton         matlab.ui.control.Button
        UIAxes_2               matlab.ui.control.UIAxes
        UIAxes                 matlab.ui.control.UIAxes
        SummaryTable           matlab.ui.container.Tab
        GridLayout2            matlab.ui.container.GridLayout
        TextArea_4             matlab.ui.control.TextArea
        TextArea               matlab.ui.control.TextArea
        FullLogLabel           matlab.ui.control.Label
        ShortSummaryLabel      matlab.ui.control.Label
        RunSummaryTableButton  matlab.ui.control.Button
    end


%-------------------------------------------------------------------------------------
   
% Properties Created
% Houses 
%   DataPath, SpecPath, Original table, Edited table, Original results,
%   Edited results, Time, Spec, TempEditedDataFile
    properties (Access = private)
        DataFilePath
        SpecFilePath

        OriginalTable
        EditedTable
        
        TimeOriginal
        TimeUpdated

        Spec

        OriginalResults
        UpdatedResults

        OriginalLog
        UpdatedLog

        TempEditedDataFile = fullfile(pwd,'temp_edited_data.xlsx')   
    end
%------------------------------------------------------------------------------------

% All the custom METHODS
    methods (Access = private)
%----------------------------------------------------%

% Load SPEC
%   Loads the USERS SPEC file into Model
%   Lets USER know if the file has load successfully or not

        function loadSpecFile(app)
            [file, path] = uigetfile({'*.xls;*.xlsx','Excel Files (*.xls, *.xlsx)'}, ...
                'Select Specification File');
        
            if isequal(file,0)
                return
            end
        
            app.SpecFilePath = fullfile(path, file);
        
            try
                app.Spec = load_spec(app.SpecFilePath);
                uialert(app.UIFigure, 'Specification file loaded successfully.', 'Spec Loaded','Icon','success');
            catch ME
                uialert(app.UIFigure, ME.message, 'Spec Load Error');
            end
        end


%------------------------------------------------------------------------------------------

% Load DATA
%   Loads the USERS DATA file into Model
%   Lets USER know if the file has load successfully or not

        function loadDataFile(app)
            [file, path] = uigetfile({'*.xls;*.xlsx','Excel Files (*.xls, *.xlsx)'}, ...
                'Select Data File');
        
            if isequal(file,0)
                return
            end
        
            app.DataFilePath = fullfile(path, file);
        
            try
                T = readtable(app.DataFilePath, 'Sheet', 'data');
        
                app.OriginalTable = T;
                app.EditedTable = T;
        
                app.UITable3.Data = app.EditedTable;
                app.UITable3.ColumnName = app.EditedTable.Properties.VariableNames;
                app.UITable3.ColumnEditable = true(1, width(T));

                if ~isempty(app.TextArea)
                    app.TextArea.Value = splitlines(evalc('disp(app.OriginalTable(1:min(10,height(app.EditedTable)),:))'));
                end

                if ~isempty(app.TextArea_4)
                    app.TextArea_4.Value = splitlines(evalc('disp(app.EditedTable(1:min(10,height(app.EditedTable)),:))'));
                end


                uialert(app.UIFigure, 'Data file loaded successfully.', 'Data Loaded',"Icon","success");
            catch ME
                uialert(app.UIFigure, ME.message, 'Data Load Error');
            end
        end

%------------------------------------------------------------------------------------------

% Saves the Edited Data to TEMPFILE
%   saveEditedDataToTempFile writes a temporary workbook so the original 
%   model code can be reused without rewriting its file-based interface.

        function saveEditedDataToTempFile(app)
            try
                if istable(app.UITable3.Data)
                    app.EditedTable = app.UITable3.Data;
                else
                    app.EditedTable = cell2table(app.UITable3.Data,"VariableNames", app.OriginalTable.Properties.VariableNames);
                end
        
                writetable(app.EditedTable, app.TempEditedDataFile, 'Sheet', 'data');
            catch ME
                error("Could not save edited data to temporary file: " + ME.message);
            end
        end
%------------------------------------------------------------------------------------------

% The RUN method
%   runModel captures command-window output with evalc so logs 
%   can be displayed inside the app.
%   Setup to run model with edited data has been started.

        function runModel(app)

            % Check that files are loaded first
            if isempty(app.SpecFilePath) || isempty(app.DataFilePath)
                uialert(app.UIFigure, ...
                    'Please load both a Spec file and a Data file first.', ...
                    'Missing Files');
                return
            end
        
            try
                % Make sure edited table exists
                if isempty(app.EditedTable)
                    app.EditedTable = app.OriginalTable;
                end
        
                % Save edited table to temporary Excel file
                saveEditedDataToTempFile(app);
        
                % Load spec if it has not already been loaded
                if isempty(app.Spec)
                    app.Spec = load_spec(app.SpecFilePath);
                end
        
                % Threshold for dfm
                threshold = 1e-4;
        
                % =========================================
                % RUN MODEL ON ORIGINAL DATA
                % =========================================
                [X_original, Time_original, ~] = load_data(app.DataFilePath, app.Spec);
        
                app.OriginalLog = evalc([ ...
                    'disp("Running original model...");' ...
                    'app.OriginalResults = dfm(X_original, app.Spec, threshold);' ...
                ]);
        
                % =========================================
                % RUN MODEL ON EDITED DATA
                % =========================================
                [X_updated, Time_updated, ~] = load_data(app.TempEditedDataFile, app.Spec);
        
                app.UpdatedLog = evalc([ ...
                    'disp("Running edited model...");' ...
                    'app.UpdatedResults = dfm(X_updated, app.Spec, threshold);' ...
                ]);
        
                % Store updated and original time vectors for plotting
                app.TimeOriginal = Time_original;
                app.TimeUpdated = Time_updated;
                
        
            catch ME
                uialert(app.UIFigure, ...
                    ['Model run failed: ' ME.message], ...
                    'Run Error');
            end
        end
%------------------------------------------------------------------------------------------

% UpdatesGraph
%   updateGraphs that the min(length(...)) logic avoids plotting 
%   errors when time and result vectors do not perfectly match.


        function updateGraphs(app)
            if isempty(app.OriginalResults) || isempty(app.UpdatedResults)
                uialert(app.UIFigure, 'Run the model first.', 'No Results');
                return
            end

            disp(length(app.TimeOriginal))
            disp(length(app.OriginalResults.X_sm(:,1)))
            disp(length(app.UpdatedResults.X_sm(:,1)))
        
            try
                cla(app.UIAxes);
                cla(app.UIAxes_2);
        
                y1 = app.OriginalResults.X_sm(:,1);
                y2 = app.UpdatedResults.X_sm(:,1);

                % Store app time vectors for original and udpdated
                t1  = app.TimeOriginal;
                t2 = app.TimeUpdated;

                n1 = min(length(t1), length(y1));
                n2 = min(length(t2), length(y2));

                % Original Data Graph
                plot(app.UIAxes, t1(1:n1), y1(1:n1), 'LineWidth', 1.5);
                title(app.UIAxes, 'Original Data');
                xlabel(app.UIAxes, 'Time');
                ylabel(app.UIAxes, 'Value');
                grid(app.UIAxes, 'on');

                % Edited Data Graph
                plot(app.UIAxes_2, t2(1:n2), y2(1:n2), 'LineWidth', 1.5);
                title(app.UIAxes_2, 'Updated Data');
                xlabel(app.UIAxes_2, 'Time');
                ylabel(app.UIAxes_2, 'Value');
                grid(app.UIAxes_2, 'on');
        
            catch ME
                uialert(app.UIFigure, ME.message, 'Graph Update Error');
            end
        end
%------------------------------------------------------------------------------------------
       %TODO: delete or find use for this function (never called)
% Updated SUMMARYTABLE
        function updateSummary(app)
            originalMean = mean(app.OriginalResults.X_sm(:,1), 'omitnan');
            updatedMean  = mean(app.UpdatedResults.X_sm(:,1), 'omitnan');
            
            outputText = sprintf([ ...
                "Summary Results\n" + ...
                "-------------------------\n\n" + ...
                "Original Mean: %.4f\n" + ...
                "Updated Mean: %.4f\n" + ...
                "Difference: %.4f\n"], ...
                originalMean, updatedMean, updatedMean - originalMean);
            
            app.TextArea.Value = splitlines(outputText);
        end
%------------------------------------------------------------------------------------------

% EXPORT
%   Exports Model results
         function exportResults(app)
            [file, path] = uiputfile({'*.xlsx','Excel Workbook (*.xlsx)'}, ...
                'Save Exported Results');
        
            if isequal(file,0)
                return
            end
        
            fullFile = fullfile(path, file);
        
            try
                % Export edited data
                if ~isempty(app.EditedTable)
                    writetable(app.EditedTable, fullFile, 'Sheet', 'EditedData');
                end

                % Export original data
                if ~isempty(app.OriginalResults)
                    origTable = array2table(app.OriginalResults.X_sm,...
                        'VariableNames', "Factor_" + (1:size(app.OriginalResults.X_sm, 2)));
                    writetable(origTable, fullFile, 'Sheet', 'OriginalModelFactors');
                end

                % Export updated data
                if ~isempty(app.UpdatedResults)
                    updTable = array2table(app.UpdatedResults.X_sm,...
                        'VariableNames', "Factor_" + (1:size(app.UpdatedResults.X_sm, 2)));
                    writetable(updTable, fullFile, 'Sheet', 'OriginalModelFactors');
                end
        
                uialert(app.UIFigure, 'Export successful.', 'Export Complete','Icon','success');
            catch ME
                uialert(app.UIFigure, ME.message, 'Export Error');
            end
        end
%------------------------------------------------------------------------------------------
% Turn DFM results into text and place into Text Area
%   Comment in displayResultsInTextArea that the left text area is intended 
%   for the short comparison summary and the right text area is intended for the full detailed log.
      function displayResultsInTextArea(app)

        if isempty(app.OriginalResults) || isempty(app.UpdatedResults)
            uialert(app.UIFigure, 'Run the model first.', 'No Results');
            return
        end
    
        try
            % -----------------------------
            % Example summary values
            % -----------------------------
            origRows = height(app.OriginalTable);
            editRows = height(app.EditedTable);
    
            origCols = width(app.OriginalTable);
            editCols = width(app.EditedTable);
    
            % Example using first smoothed series / factor
            origMean = mean(app.OriginalResults.X_sm(:,1), 'omitnan');
            editMean = mean(app.UpdatedResults.X_sm(:,1), 'omitnan');
            diffMean = editMean - origMean;
    
            % -----------------------------
            % Combined summary box
            % -----------------------------
            summaryLines = {
                'SUMMARY COMPARISON'
                '------------------'
                ['Original rows loaded: ' num2str(origRows)]
                ['Edited rows loaded: ' num2str(editRows)]
                ' '
                ['Original columns loaded: ' num2str(origCols)]
                ['Edited columns loaded: ' num2str(editCols)]
                ' '
                ['Original Series 1 mean: ' num2str(origMean, '%.4f')]
                ['Edited Series 1 mean: ' num2str(editMean, '%.4f')]
                ['Difference: ' num2str(diffMean, '%.4f')]
            };
    
            app.TextArea.Value = summaryLines;
    
            % -----------------------------
            % Full combined log box
            % -----------------------------
            fullLog = [
                "===== ORIGINAL MODEL OUTPUT ====="
                splitlines(string(app.OriginalLog))
                ""
                "===== EDITED MODEL OUTPUT ====="
                splitlines(string(app.UpdatedLog))
            ];
    
            app.TextArea_4.Value = cellstr(fullLog);
    
        catch ME
            uialert(app.UIFigure, ...
                ['Could not display results: ' ME.message], ...
                'Display Error');
        end
    end

%------------------------------------------------------------------------------------------

% The end of Custom METHODS
    end
%------------------------------------------------------------------------------------------


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Add the functions/ folder relative to this app's location
            appDir = fileparts(which('app1.mlapp'));
            addpath(fullfile(appDir, 'functions'));
            app.TempEditedDataFile = fullfile(appDir, 'temp_edited_data.xlsx');
        end

        % Button pushed function: LoadSpecFileButton
        function LoadSpecFileButtonPushed(app, event)
            loadSpecFile(app);
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            exportResults(app);
        end

        % Button pushed function: RunGraphButton
        function RunGraphButtonPushed(app, event)
            runModel(app);
            updateGraphs(app);
        end

        % Button pushed function: RunSummaryTableButton
        function RunSummaryTableButtonPushed(app, event)
            runModel(app);
            displayResultsInTextArea(app);
        end

        % Button pushed function: LoadDataFileButton
        function LoadDataFileButtonPushed(app, event)
            loadDataFile(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MainTab_2 = uitab(app.TabGroup);
            app.MainTab_2.Title = 'Main';
            app.GridLayout = uigridlayout(app.MainTab_2);
            app.GDPNowcastingAppLabel = uilabel(app.GridLayout);
            app.GDPNowcastingAppLabel.Text = 'GDP Nowcasting App';
            app.Hyperlink = uihyperlink(app.GridLayout);
            app.Hyperlink.URL = 'https://github.com/FRBNY-TimeSeriesAnalysis/Nowcasting';
            app.Hyperlink.Text = 'Nowcasting GitHub Repository';
            app.LoadSpecFileButton = uibutton(app.GridLayout, 'push');
            app.LoadSpecFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSpecFileButtonPushed, true);
            app.LoadSpecFileButton.Text = 'Load Spec File';
            app.ExportButton = uibutton(app.GridLayout, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.LoadDataFileButton = uibutton(app.GridLayout, 'push');
            app.LoadDataFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataFileButtonPushed, true);
            app.LoadDataFileButton.Text = 'Load Data File';
            app.TextArea_3 = uitextarea(app.GridLayout);
            app.TextArea_3.Editable = 'off';
            app.TextArea_3.Value = {'This is a nowcasting app.'; ''; 'The model requires two Excel files to run.'; ''; 'Use the buttons below to load each required file.'; ''; 'The sheet inside the Data Excel file must be named "data".'; ''; 'The Data Excel file must also contain the matching SeriesIDs listed in the Spec Excel file.'; ''; 'If these conditions are not met, the model may return an out of bounds error.'};
            app.GraphTab = uitab(app.TabGroup);
            app.GraphTab.Title = 'Graph';
            app.UIAxes = uiaxes(app.GraphTab);
            title(app.UIAxes, 'Original')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.GraphTab);
            title(app.UIAxes_2, 'Edited Data')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.RunGraphButton = uibutton(app.GraphTab, 'push');
            app.RunGraphButton.ButtonPushedFcn = createCallbackFcn(app, @RunGraphButtonPushed, true);
            app.RunGraphButton.Text = 'Run: Graph';
            app.UITable3 = uitable(app.GraphTab);
            app.UITable3.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable3.RowName = {};
            app.SummaryTable = uitab(app.TabGroup);
            app.SummaryTable.Title = 'Summary Table';
            app.GridLayout2 = uigridlayout(app.SummaryTable);
            app.RunSummaryTableButton = uibutton(app.GridLayout2, 'push');
            app.RunSummaryTableButton.ButtonPushedFcn = createCallbackFcn(app, @RunSummaryTableButtonPushed, true);
            app.RunSummaryTableButton.Text = 'Run: Summary Table';
            app.ShortSummaryLabel = uilabel(app.GridLayout2);
            app.ShortSummaryLabel.Text = 'Short Summary';
            app.FullLogLabel = uilabel(app.GridLayout2);
            app.FullLogLabel.Text = 'Full Log';
            app.TextArea = uitextarea(app.GridLayout2);
            app.TextArea_4 = uitextarea(app.GridLayout2);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end